classdef SocketSenseMainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        GridLayout5                  matlab.ui.container.GridLayout
        Image                        matlab.ui.control.Image
        GridLayout7                  matlab.ui.container.GridLayout
        TabGroup                     matlab.ui.container.TabGroup
        OpenSimTab                   matlab.ui.container.Tab
        GridLayout9                  matlab.ui.container.GridLayout
        UIAxes_Force                 matlab.ui.control.UIAxes
        UIAxes_Torque                matlab.ui.control.UIAxes
        SimulinkTab                  matlab.ui.container.Tab
        GridLayout10                 matlab.ui.container.GridLayout
        UIAxes_Sim_Out_Force         matlab.ui.control.UIAxes
        RigMotorPositionsTab         matlab.ui.container.Tab
        GridLayout11                 matlab.ui.container.GridLayout
        Motor_1_Position             matlab.ui.control.UIAxes
        Motor_2_Position             matlab.ui.control.UIAxes
        Motor_3_Position             matlab.ui.control.UIAxes
        Motor_4_Position             matlab.ui.control.UIAxes
        Motor_5_Position             matlab.ui.control.UIAxes
        Motor_6_Position             matlab.ui.control.UIAxes
        RigMotorForceTab             matlab.ui.container.Tab
        GridLayout12                 matlab.ui.container.GridLayout
        Motor_Force_1                matlab.ui.control.UIAxes
        Motor_Force_2                matlab.ui.control.UIAxes
        Motor_Force_3                matlab.ui.control.UIAxes
        Motor_Force_4                matlab.ui.control.UIAxes
        Motor_Force_5                matlab.ui.control.UIAxes
        Motor_Force_6                matlab.ui.control.UIAxes
        GridLayout8                  matlab.ui.container.GridLayout
        GridLayout6                  matlab.ui.container.GridLayout
        Lamp_Status                  matlab.ui.control.Lamp
        Lamp_Arduino                 matlab.ui.control.Lamp
        LampStatusLabel              matlab.ui.control.Label
        ArduinoLabel                 matlab.ui.control.Label
        RecordPositionControlButton  matlab.ui.control.Button
        RecordForceControlButton     matlab.ui.control.Button
        RunSimulationButton          matlab.ui.control.Button
        LoadDataButton               matlab.ui.control.Button
        SettingsPanel                matlab.ui.container.Panel
        GridLayout4                  matlab.ui.container.GridLayout
        PeriodsEditFieldLabel        matlab.ui.control.Label
        PeriodsEditField             matlab.ui.control.NumericEditField
        StepSizeDropDownLabel        matlab.ui.control.Label
        StepSizeDropDown             matlab.ui.control.DropDown
        RecTimeEditFieldLabel        matlab.ui.control.Label
        RecTimeEditField             matlab.ui.control.EditField
        MovementProfilePanel         matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        FileDropDownLabel            matlab.ui.control.Label
        FileDropDown                 matlab.ui.control.DropDown
        Panel                        matlab.ui.container.Panel
        StatusPanelGridLayout        matlab.ui.container.GridLayout
        StatusLabel                  matlab.ui.control.Label
    end

    
    properties (Access = public)
        currentFolder = pwd;
        % openSimDataPath = join([app.currentFolder, '/Tests/opensim_data.mat']);
        openSimDataPath = 'OpenSimData/Suran_23rd.mat';
        % simulinkModel = join([app.currentFolder, '/Tests/F3_Trajectory.slx']);
        simulinkModel = 'Custom_SP';
        STEP_SIZE = 0.01;
        GAIT_CYCLES = 1;
        GAIT_TIME = 10;
        GAIT_STOP_TIME = 10;
        REC_TIME = 10;
        
        % Lamp colors
        RED = [1,0,0];
        GREEN = [0.47,0.67,0.19];
        BLUE = [0,0,1];
        YELLOW = [0.93,0.69,0.13];
        BLACK = [0,0,0];
        WHITE = [1,1,1];
        
        % ModelVariables
        LegSpring = 50000;
        LegDamp = 1000;
        
        % StatusText
        StatusText = [""];
        
    end
    
    methods (Access = public)
        
        function results = loadOpenSimData(app)
            data = load(app.openSimDataPath);
            results = data;
        end
    end
    
    methods (Access = private)
        
        
        function results = new_status(app, msg)
            app.StatusText(end+1) = msg;
            results = app.StatusText;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            open(app.simulinkModel)
            app.Lamp_Status.Color = app.WHITE;
            app.Lamp_Arduino.Color = app.WHITE;
            
            assignin('base',"LegSpring", app.LegSpring);
            assignin('base',"LegDamp", app.LegDamp);
            
            %pyenv("Version", "/PythonScripts/venv/myenv/bin/python3")
            P = py.sys.path;
            if count(P,'') == 0
                insert(py.sys.path,int32(0),'');
            end
            py.importlib.import_module('mat4py');
            %py.importlib.reload(mod);
            
            app.StatusLabel.Text = app.StatusText;
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            %app.Lamp_Status.Color = app.YELLOW;
            %app.StatusLabel.Text = app.new_status("Loading Data...");
            app.StatusLabel.Text = "Loading Data";
            
            OPENSIM_DATA = loadOpenSimData(app);
            FX = OPENSIM_DATA.matlabStruct_Data.socket_piston_x_force;
            FY = OPENSIM_DATA.matlabStruct_Data.socket_piston_y_force;
            FZ = OPENSIM_DATA.matlabStruct_Data.socket_piston_z_force;
            MX = OPENSIM_DATA.matlabStruct_Data.socket_rotation_x_moment;
            MY = OPENSIM_DATA.matlabStruct_Data.socket_rotation_y_moment;
            MZ = OPENSIM_DATA.matlabStruct_Data.socket_rotation_z_moment;
            TIME = OPENSIM_DATA.matlabStruct_Data.time;
            app.GAIT_TIME = max(TIME) - min(TIME);
            
            % Plot Forces
            plot(app.UIAxes_Force, TIME, FX);
            hold(app.UIAxes_Force);
            plot(app.UIAxes_Force, TIME, FY);
            plot(app.UIAxes_Force, TIME, FZ);
            hold(app.UIAxes_Force);
            legend(app.UIAxes_Force, "X", "Y", "Z");
            
            % Plot Torques
            plot(app.UIAxes_Torque, TIME, MX);
            hold(app.UIAxes_Torque);
            plot(app.UIAxes_Torque, TIME, MY);
            plot(app.UIAxes_Torque, TIME, MZ);
            hold(app.UIAxes_Torque);
            legend(app.UIAxes_Torque, "X", "Y", "Z");
            
            % Store variables in workspace
            assignin('base',"TIME", TIME)
            assignin('base',"FX", FX)
            assignin('base',"FY", FY)
            assignin('base',"FZ", FZ)
            assignin('base',"MX", MX)
            assignin('base',"MY", MY)
            assignin('base',"MZ", MZ)
            
            % Update Repeated Sequence blocks in Simulink
            set_param('Custom_SP/xForce', 'rep_seq_t', 'TIME', 'rep_seq_y','FX')
            set_param('Custom_SP/yForce', 'rep_seq_t', 'TIME', 'rep_seq_y','FY')
            set_param('Custom_SP/zForce', 'rep_seq_t', 'TIME', 'rep_seq_y','FZ')
            set_param('Custom_SP/xTorque', 'rep_seq_t', 'TIME', 'rep_seq_y','MX')
            set_param('Custom_SP/yTorque', 'rep_seq_t', 'TIME', 'rep_seq_y','MY')
            set_param('Custom_SP/zTorque', 'rep_seq_t', 'TIME', 'rep_seq_y','MZ')
            
            %app.StatusLabel.Text = app.new_status("Loading Done.");
            app.StatusLabel.Text = "Loading Done.";
            app.Lamp_Status.Color = app.GREEN;
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            app.Lamp_Status.Color = app.YELLOW;
            %app.StatusLabel.Text = app.new_status("Running Simulink...");
            app.StatusLabel.Text = "Running Simulink";
            
            simout = sim(app.simulinkModel, 'StopTime', int2str(app.GAIT_STOP_TIME), 'FixedStep', num2str(app.STEP_SIZE));
            assignin('base', "simout", simout);
            Forces = simout.Forces;
            save("SimulinkData/simout","Forces", '-nocompression')
            %PositionMatrix=(round(squeeze(out.Position.signals.values(:,1,:))))';
            %save('PosMat.mat','PositionMatrix');
            
            time = evalin('base','simout.Forces.time');
            
            % Simulink forces
            a = evalin('base','simout.Forces.signals.values(:,1)');
            b = evalin('base','simout.Forces.signals.values(:,2)');
            c = evalin('base','simout.Forces.signals.values(:,3)');
            d = evalin('base','simout.Forces.signals.values(:,4)');
            e = evalin('base','simout.Forces.signals.values(:,5)');
            f = evalin('base','simout.Forces.signals.values(:,6)');
           
            % Simulink Forces
            plot(app.UIAxes_Sim_Out_Force, time, a);
            hold(app.UIAxes_Sim_Out_Force);
            plot(app.UIAxes_Sim_Out_Force, time, b);
            plot(app.UIAxes_Sim_Out_Force, time, c);
            plot(app.UIAxes_Sim_Out_Force, time, d);
            plot(app.UIAxes_Sim_Out_Force, time, e);
            plot(app.UIAxes_Sim_Out_Force, time, f);
            hold(app.UIAxes_Sim_Out_Force);
            legend(app.UIAxes_Sim_Out_Force, "Motor 1", "Motor 2", "Motor 3", "Motor 4", "Motor 5", "Motor 6");

            app.Lamp_Status.Color = app.GREEN;
            app.Lamp_Arduino.Color = app.GREEN;
            app.StatusLabel.Text = "Simulation Done.";
            
        end

        % Value changed function: PeriodsEditField
        function PeriodsEditFieldValueChanged(app, event)
            value = app.PeriodsEditField.Value;
            app.GAIT_CYCLES = value;
            app.GAIT_STOP_TIME = app.GAIT_CYCLES * app.GAIT_TIME;
        end

        % Value changed function: StepSizeDropDown
        function StepSizeDropDownValueChanged(app, event)
            value = app.StepSizeDropDown.Value;
            app.STEP_SIZE = value;
        end

        % Callback function
        function UploadButtonPushed(app, event)
            
        end

        % Value changed function: FileDropDown
        function FileDropDownValueChanged(app, event)
            value = app.FileDropDown.Value;
            if value == 1
                app.openSimDataPath = 'OpenSimData/Suran_23rd.mat';
            elseif value == 2
                app.openSimDataPath = 'OpenSimData/opensim_data.mat';
            end
        end

        % Button pushed function: RecordForceControlButton
        function RecordForceControlButtonPushed(app, event)
            %sensorData = load("ArduinoSensorData/SensorOutput_Mock.mat");
            
            app.StatusLabel.Text = "Recording sensor data";
            app.Lamp_Status.Color = app.YELLOW;
            app.Lamp_Arduino.Color = app.RED;
            rec_time = py.int(app.REC_TIME);
            path = py.Simout2Arduino_terminal.serial_read_sensors_matlab(rec_time);
            app.StatusLabel.Text = "Done";
            app.Lamp_Status.Color = app.GREEN;
            app.Lamp_Arduino.Color = app.GREEN;
            sensorData = load("ArduinoSensorData/SensorOutput.mat");
            
            assignin('base', "sensor_data", sensorData);
            
            time = evalin('base','sensor_data.sensor_time');
            
            % Simulink Arduino Forces (but with some noise to simulate actual
            % sensor values)
            g = evalin('base','sensor_data.sensor_force(:,1)');
            h = evalin('base','sensor_data.sensor_force(:,2)');
            i = evalin('base','sensor_data.sensor_force(:,3)');
            j = evalin('base','sensor_data.sensor_force(:,4)');
            k = evalin('base','sensor_data.sensor_force(:,5)');
            l = evalin('base','sensor_data.sensor_force(:,6)');
            
            % Simulink Arduino Position (but with some noise to simulate actual
            % sensor values)
            m = evalin('base','sensor_data.sensor_position(:,1)');
            n = evalin('base','sensor_data.sensor_position(:,2)');
            o = evalin('base','sensor_data.sensor_position(:,3)');
            p = evalin('base','sensor_data.sensor_position(:,4)');
            q = evalin('base','sensor_data.sensor_position(:,5)');
            r = evalin('base','sensor_data.sensor_position(:,6)');
            
            % Simulink Arduino Ref Position
            s = evalin('base','sensor_data.ref_position(:,1)');
            t = evalin('base','sensor_data.ref_position(:,2)');
            u = evalin('base','sensor_data.ref_position(:,3)');
            v = evalin('base','sensor_data.ref_position(:,4)');
            x = evalin('base','sensor_data.ref_position(:,5)');
            y = evalin('base','sensor_data.ref_position(:,6)');
            
            % Arduino Force 1
            plot(app.Motor_Force_1, time, g);
            hold(app.Motor_Force_1);
            plot(app.Motor_Force_1, time, s);
            hold(app.Motor_Force_1);
            legend(app.Motor_Force_1, "Rig Force", "Ref");
            
            % Arduino Force 2
            plot(app.Motor_Force_2, time, h);
            hold(app.Motor_Force_2);
            plot(app.Motor_Force_2, time, t);
            hold(app.Motor_Force_2);
            legend(app.Motor_Force_2, "Rig Force", "Ref");
            
            % Arduino Force 3
            plot(app.Motor_Force_3, time, i);
            hold(app.Motor_Force_3);
            plot(app.Motor_Force_3, time, u);
            hold(app.Motor_Force_3);
            legend(app.Motor_Force_3, "Rig Force", "Ref");
            
            % Arduino Force 4
            plot(app.Motor_Force_4, time, j);
            hold(app.Motor_Force_4);
            plot(app.Motor_Force_4, time, v);
            hold(app.Motor_Force_4);
            legend(app.Motor_Force_4, "Rig Force", "Ref");
            
            % Arduino Force 5
            plot(app.Motor_Force_5, time, k);
            hold(app.Motor_Force_5);
            plot(app.Motor_Force_5, time, x);
            hold(app.Motor_Force_5);
            legend(app.Motor_Force_5, "Rig Force", "Ref");
            
            % Arduino Force 6
            plot(app.Motor_Force_6, time, l);
            hold(app.Motor_Force_6);
            plot(app.Motor_Force_6, time, y);
            hold(app.Motor_Force_6);
            legend(app.Motor_Force_6, "Rig Force", "Ref");
        end

        % Button pushed function: RecordPositionControlButton
        function RecordPositionControlButtonPushed(app, event)
            app.StatusLabel.Text = "Recording sensor data";
            app.Lamp_Status.Color = app.YELLOW;
            app.Lamp_Arduino.Color = app.RED;
            rec_time = py.int(app.REC_TIME);
            path = py.Simout2Arduino_terminal.serial_read_sensors_matlab(rec_time);
            app.StatusLabel.Text = "Done";
            app.Lamp_Status.Color = app.GREEN;
            app.Lamp_Arduino.Color = app.GREEN;
            sensorData = load("ArduinoSensorData/SensorOutput.mat");
            assignin('base', "sensor_data", sensorData);
            
            time = evalin('base','sensor_data.sensor_time');
            
            % Simulink Arduino Forces (but with some noise to simulate actual
            % sensor values)
            g = evalin('base','sensor_data.sensor_force(:,1)');
            h = evalin('base','sensor_data.sensor_force(:,2)');
            i = evalin('base','sensor_data.sensor_force(:,3)');
            j = evalin('base','sensor_data.sensor_force(:,4)');
            k = evalin('base','sensor_data.sensor_force(:,5)');
            l = evalin('base','sensor_data.sensor_force(:,6)');
            
            % Simulink Arduino Position (but with some noise to simulate actual
            % sensor values)
            m = evalin('base','sensor_data.sensor_position(:,1)');
            n = evalin('base','sensor_data.sensor_position(:,2)');
            o = evalin('base','sensor_data.sensor_position(:,3)');
            p = evalin('base','sensor_data.sensor_position(:,4)');
            q = evalin('base','sensor_data.sensor_position(:,5)');
            r = evalin('base','sensor_data.sensor_position(:,6)');
            
            % Simulink Arduino Ref Position
            s = evalin('base','sensor_data.ref_position(:,1)');
            t = evalin('base','sensor_data.ref_position(:,2)');
            u = evalin('base','sensor_data.ref_position(:,3)');
            v = evalin('base','sensor_data.ref_position(:,4)');
            x = evalin('base','sensor_data.ref_position(:,5)');
            y = evalin('base','sensor_data.ref_position(:,6)');
            
            % Arduino Position 1
            plot(app.Motor_1_Position, time, m);
            hold(app.Motor_1_Position);
            plot(app.Motor_1_Position, time, s);
            hold(app.Motor_1_Position);
            legend(app.Motor_1_Position, "Rig Position", "Ref");
            
            % Arduino Position 2
            plot(app.Motor_2_Position, time, n);
            hold(app.Motor_2_Position);
            plot(app.Motor_2_Position, time, t);
            hold(app.Motor_2_Position);
            legend(app.Motor_2_Position, "Rig Position", "Ref");
            
            % Arduino Position 3
            plot(app.Motor_3_Position, time, o);
            hold(app.Motor_3_Position);
            plot(app.Motor_3_Position, time, u);
            hold(app.Motor_3_Position);
            legend(app.Motor_3_Position, "Rig Position", "Ref");
            
            % Arduino Position 4
            plot(app.Motor_4_Position, time, p);
            hold(app.Motor_4_Position);
            plot(app.Motor_4_Position, time, v);
            hold(app.Motor_4_Position);
            legend(app.Motor_4_Position, "Rig Position", "Ref");
            
            % Arduino Position 5
            plot(app.Motor_5_Position, time, q);
            hold(app.Motor_5_Position);
            plot(app.Motor_5_Position, time, x);
            hold(app.Motor_5_Position);
            legend(app.Motor_5_Position, "Rig Position", "Ref");
            
            % Arduino Position 6
            plot(app.Motor_6_Position, time, r);
            hold(app.Motor_6_Position);
            plot(app.Motor_6_Position, time, y);
            hold(app.Motor_6_Position);
            legend(app.Motor_6_Position, "Rig Position", "Ref");
        end

        % Value changed function: RecTimeEditField
        function RecTimeEditFieldValueChanged(app, event)
            value = app.RecTimeEditField.Value;
            app.REC_TIME = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.Image = uiimage(app.GridLayout5);
            app.Image.ImageSource = 'Socket-Sense-Logo.png';
            app.GridLayout7 = uigridlayout(app.GridLayout);
            app.TabGroup = uitabgroup(app.GridLayout7);
            app.OpenSimTab = uitab(app.TabGroup);
            app.OpenSimTab.Title = 'OpenSim';
            app.GridLayout9 = uigridlayout(app.OpenSimTab);
            app.UIAxes_Force = uiaxes(app.GridLayout9);
            title(app.UIAxes_Force, 'OpenSim Forces')
            xlabel(app.UIAxes_Force, 'Time')
            ylabel(app.UIAxes_Force, 'Force [N]')
            app.UIAxes_Torque = uiaxes(app.GridLayout9);
            title(app.UIAxes_Torque, 'OpenSim Torques')
            xlabel(app.UIAxes_Torque, 'Time')
            ylabel(app.UIAxes_Torque, 'Torque [Nm]')
            app.SimulinkTab = uitab(app.TabGroup);
            app.SimulinkTab.Title = 'Simulink';
            app.GridLayout10 = uigridlayout(app.SimulinkTab);
            app.UIAxes_Sim_Out_Force = uiaxes(app.GridLayout10);
            title(app.UIAxes_Sim_Out_Force, 'Simulink Motor Forces')
            xlabel(app.UIAxes_Sim_Out_Force, 'Time [s]')
            ylabel(app.UIAxes_Sim_Out_Force, 'Force [N]')
            zlabel(app.UIAxes_Sim_Out_Force, 'Z')
            app.RigMotorPositionsTab = uitab(app.TabGroup);
            app.RigMotorPositionsTab.Title = 'Rig Motor Positions';
            app.GridLayout11 = uigridlayout(app.RigMotorPositionsTab);
            app.Motor_1_Position = uiaxes(app.GridLayout11);
            title(app.Motor_1_Position, 'Motor 1 Position')
            xlabel(app.Motor_1_Position, 'Time [s]')
            ylabel(app.Motor_1_Position, 'Position [0.1 mm]')
            zlabel(app.Motor_1_Position, 'Z')
            app.Motor_2_Position = uiaxes(app.GridLayout11);
            title(app.Motor_2_Position, 'Motor 2 Position')
            xlabel(app.Motor_2_Position, 'Time [s]')
            ylabel(app.Motor_2_Position, 'Position [0.1 mm]')
            zlabel(app.Motor_2_Position, 'Z')
            app.Motor_2_Position.YTick = [0 0.5 1];
            app.Motor_3_Position = uiaxes(app.GridLayout11);
            title(app.Motor_3_Position, 'Motor 3 Position')
            xlabel(app.Motor_3_Position, 'Time [s]')
            ylabel(app.Motor_3_Position, 'Position [0.1 mm]')
            zlabel(app.Motor_3_Position, 'Z')
            app.Motor_3_Position.YTick = [0 0.5 1];
            app.Motor_4_Position = uiaxes(app.GridLayout11);
            title(app.Motor_4_Position, 'Motor 4 Position')
            xlabel(app.Motor_4_Position, 'Time [s]')
            ylabel(app.Motor_4_Position, 'Position [0.1 mm]')
            zlabel(app.Motor_4_Position, 'Z')
            app.Motor_4_Position.YTick = [0 0.5 1];
            app.Motor_5_Position = uiaxes(app.GridLayout11);
            title(app.Motor_5_Position, 'Motor 5 Position')
            xlabel(app.Motor_5_Position, 'Time [s]')
            ylabel(app.Motor_5_Position, 'Position [0.1 mm]')
            zlabel(app.Motor_5_Position, 'Z')
            app.Motor_5_Position.YTick = [0 0.5 1];
            app.Motor_6_Position = uiaxes(app.GridLayout11);
            title(app.Motor_6_Position, 'Motor 6 Position')
            xlabel(app.Motor_6_Position, 'Time [s]')
            ylabel(app.Motor_6_Position, 'Position [0.1 mm]')
            zlabel(app.Motor_6_Position, 'Z')
            app.Motor_6_Position.YTick = [0 0.5 1];
            app.RigMotorForceTab = uitab(app.TabGroup);
            app.RigMotorForceTab.Title = 'Rig Motor Force';
            app.GridLayout12 = uigridlayout(app.RigMotorForceTab);
            app.Motor_Force_1 = uiaxes(app.GridLayout12);
            title(app.Motor_Force_1, 'Motor 1 Force')
            xlabel(app.Motor_Force_1, 'Time')
            ylabel(app.Motor_Force_1, 'Force [N]')
            zlabel(app.Motor_Force_1, 'Z')
            app.Motor_Force_2 = uiaxes(app.GridLayout12);
            title(app.Motor_Force_2, 'Motor 2 Force')
            xlabel(app.Motor_Force_2, 'Time')
            ylabel(app.Motor_Force_2, 'Force [N]')
            zlabel(app.Motor_Force_2, 'Z')
            app.Motor_Force_2.YTick = [0 0.5 1];
            app.Motor_Force_3 = uiaxes(app.GridLayout12);
            title(app.Motor_Force_3, 'Motor 3 Force')
            xlabel(app.Motor_Force_3, 'Time')
            ylabel(app.Motor_Force_3, 'Force [N]')
            zlabel(app.Motor_Force_3, 'Z')
            app.Motor_Force_3.YTick = [0 0.5 1];
            app.Motor_Force_4 = uiaxes(app.GridLayout12);
            title(app.Motor_Force_4, 'Motor 4 Force')
            xlabel(app.Motor_Force_4, 'Time')
            ylabel(app.Motor_Force_4, 'Force [N]')
            zlabel(app.Motor_Force_4, 'Z')
            app.Motor_Force_4.YTick = [0 0.5 1];
            app.Motor_Force_5 = uiaxes(app.GridLayout12);
            title(app.Motor_Force_5, 'Motor 5 Force')
            xlabel(app.Motor_Force_5, 'Time')
            ylabel(app.Motor_Force_5, 'Force [N]')
            zlabel(app.Motor_Force_5, 'Z')
            app.Motor_Force_5.YTick = [0 0.5 1];
            app.Motor_Force_6 = uiaxes(app.GridLayout12);
            title(app.Motor_Force_6, 'Motor 6 Force')
            xlabel(app.Motor_Force_6, 'Time')
            ylabel(app.Motor_Force_6, 'Force [N]')
            zlabel(app.Motor_Force_6, 'Z')
            app.Motor_Force_6.YTick = [0 0.5 1];
            app.GridLayout8 = uigridlayout(app.GridLayout);
            app.GridLayout6 = uigridlayout(app.GridLayout8);
            app.Lamp_Status = uilamp(app.GridLayout6);
            app.Lamp_Arduino = uilamp(app.GridLayout6);
            app.LampStatusLabel = uilabel(app.GridLayout6);
            app.LampStatusLabel.Text = 'Simulink';
            app.ArduinoLabel = uilabel(app.GridLayout6);
            app.ArduinoLabel.Text = 'Arduino';
            app.RecordPositionControlButton = uibutton(app.GridLayout8, 'push');
            app.RecordPositionControlButton.ButtonPushedFcn = createCallbackFcn(app, @RecordPositionControlButtonPushed, true);
            app.RecordPositionControlButton.Icon = 'eye.svg';
            app.RecordPositionControlButton.Text = 'Record (Position Control)';
            app.RecordForceControlButton = uibutton(app.GridLayout8, 'push');
            app.RecordForceControlButton.ButtonPushedFcn = createCallbackFcn(app, @RecordForceControlButtonPushed, true);
            app.RecordForceControlButton.Icon = 'eye.svg';
            app.RecordForceControlButton.Text = 'Record (Force Control)';
            app.RunSimulationButton = uibutton(app.GridLayout8, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Icon = 'chevron-right.svg';
            app.RunSimulationButton.Text = 'Run Simulation';
            app.LoadDataButton = uibutton(app.GridLayout8, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Icon = 'download.svg';
            app.LoadDataButton.Text = 'Load Data';
            app.SettingsPanel = uipanel(app.GridLayout8);
            app.SettingsPanel.Title = 'Settings';
            app.GridLayout4 = uigridlayout(app.SettingsPanel);
            app.PeriodsEditFieldLabel = uilabel(app.GridLayout4);
            app.PeriodsEditFieldLabel.Text = 'Periods';
            app.PeriodsEditField = uieditfield(app.GridLayout4, 'numeric');
            app.PeriodsEditField.Limits = [1 20];
            app.PeriodsEditField.ValueChangedFcn = createCallbackFcn(app, @PeriodsEditFieldValueChanged, true);
            app.PeriodsEditField.Value = 1;
            app.StepSizeDropDownLabel = uilabel(app.GridLayout4);
            app.StepSizeDropDownLabel.Text = 'Step Size';
            app.StepSizeDropDown = uidropdown(app.GridLayout4);
            app.StepSizeDropDown.Items = {'0.1', '0.05', '0.01', '0.005', '0.001'};
            app.StepSizeDropDown.ItemsData = {'0.1', '0.05', '0.01', '0.005', '0.001'};
            app.StepSizeDropDown.ValueChangedFcn = createCallbackFcn(app, @StepSizeDropDownValueChanged, true);
            app.StepSizeDropDown.Value = '0.01';
            app.RecTimeEditFieldLabel = uilabel(app.GridLayout4);
            app.RecTimeEditFieldLabel.Text = 'Rec Time';
            app.RecTimeEditField = uieditfield(app.GridLayout4, 'text');
            app.RecTimeEditField.ValueChangedFcn = createCallbackFcn(app, @RecTimeEditFieldValueChanged, true);
            app.RecTimeEditField.Value = '10';
            app.MovementProfilePanel = uipanel(app.GridLayout8);
            app.MovementProfilePanel.Title = 'Movement Profile';
            app.GridLayout2 = uigridlayout(app.MovementProfilePanel);
            app.FileDropDownLabel = uilabel(app.GridLayout2);
            app.FileDropDownLabel.Text = 'File';
            app.FileDropDown = uidropdown(app.GridLayout2);
            app.FileDropDown.Items = {'Suranjan'};
            app.FileDropDown.ItemsData = 1;
            app.FileDropDown.ValueChangedFcn = createCallbackFcn(app, @FileDropDownValueChanged, true);
            app.FileDropDown.Value = 1;
            app.Panel = uipanel(app.GridLayout8);
            app.StatusPanelGridLayout = uigridlayout(app.Panel);
            app.StatusLabel = uilabel(app.StatusPanelGridLayout);
            app.StatusLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SocketSenseMainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end