classdef SocketSenseMainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LoadOpenSimDataButton          matlab.ui.control.Button
        UIAxes_Torque                  matlab.ui.control.UIAxes
        RunSimulationButton            matlab.ui.control.Button
        RunTestRigForcefeedbackButton  matlab.ui.control.Button
        DemoModeButton                 matlab.ui.control.Button
        PatientProfilePanel            matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        KgDropDownLabel                matlab.ui.control.Label
        KgDropDown                     matlab.ui.control.DropDown
        UIAxes_Force                   matlab.ui.control.UIAxes
        SettingsPanel                  matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        GaitCyclesEditField_2Label     matlab.ui.control.Label
        GaitCyclesEditField            matlab.ui.control.NumericEditField
        StepSizeDropDownLabel          matlab.ui.control.Label
        StepSizeDropDown               matlab.ui.control.DropDown
        UIAxes_Sim_Out_Force           matlab.ui.control.UIAxes
        UIAxes_Sim_Out_Torque          matlab.ui.control.UIAxes
        UIAxes_Arduino_Out_Force       matlab.ui.control.UIAxes
        UIAxes_Arduino_Out_Force_2     matlab.ui.control.UIAxes
        GridLayout5                    matlab.ui.container.GridLayout
        Image                          matlab.ui.control.Image
        GridLayout6                    matlab.ui.container.GridLayout
        Lamp_Status                    matlab.ui.control.Lamp
        Lamp_Arduino                   matlab.ui.control.Lamp
        StatusLabel                    matlab.ui.control.Label
        ArduinoLabel                   matlab.ui.control.Label
    end

    
    properties (Access = public)
        currentFolder = pwd;
        % openSimDataPath = join([app.currentFolder, '/Tests/opensim_data.mat']);
        openSimDataPath = 'Tests/opensim_data.mat';
        % simulinkModel = join([app.currentFolder, '/Tests/F3_Trajectory.slx']);
        simulinkModel = 'F3_Trajectory';
        STEP_SIZE = 0.01;
        GAIT_CYCLES = 1;
        GAIT_TIME = 2;
        GAIT_STOP_TIME = 2;
        
        % Lamp colors
        RED = [1,0,0];
        GREEN = [0,1,0];
        BLUE = [0,0,1];
        YELLOW = [1,1,0];
        BLACK = [0,0,0];
        WHITE = [1,1,1];
        
    end
    
    methods (Access = public)
        
        function results = loadOpenSimData(app)
            data = load(app.openSimDataPath);
            results = data;
        end
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            open(app.simulinkModel)
            app.Lamp_Status.Color = app.WHITE;
        end

        % Button pushed function: LoadOpenSimDataButton
        function LoadOpenSimDataButtonPushed(app, event)
            app.Lamp_Status.Color = app.YELLOW;
            
            OPENSIM_DATA = loadOpenSimData(app);
            FX = OPENSIM_DATA.matlabStruct_Data.interface_r_on_socket_r_in_ground_fx;
            FY = OPENSIM_DATA.matlabStruct_Data.interface_r_on_socket_r_in_ground_fy;
            FZ = OPENSIM_DATA.matlabStruct_Data.interface_r_on_socket_r_in_ground_fz;
            MX = OPENSIM_DATA.matlabStruct_Data.interface_r_on_socket_r_in_ground_mx;
            MY = OPENSIM_DATA.matlabStruct_Data.interface_r_on_socket_r_in_ground_my;
            MZ = OPENSIM_DATA.matlabStruct_Data.interface_r_on_socket_r_in_ground_mz;
            TIME = OPENSIM_DATA.matlabStruct_Data.time;
            app.GAIT_TIME = max(TIME) - min(TIME);
            
            % Plot Forces
            plot(app.UIAxes_Force, TIME, FX, '-r');
            hold(app.UIAxes_Force);
            plot(app.UIAxes_Force, TIME, FY, '--b');
            plot(app.UIAxes_Force, TIME, FZ, '--g');
            hold(app.UIAxes_Force);
            
            % Plot Torques
            plot(app.UIAxes_Torque, TIME, MX, '-r');
            hold(app.UIAxes_Torque);
            plot(app.UIAxes_Torque, TIME, MY, '--b');
            plot(app.UIAxes_Torque, TIME, MZ, '--g');
            hold(app.UIAxes_Torque);
            
            % Store variables in workspace
            assignin('base',"TIME", TIME)
            assignin('base',"FX", FX)
            assignin('base',"FY", FY)
            assignin('base',"FZ", FZ)
            assignin('base',"MX", MX)
            assignin('base',"MY", MY)
            assignin('base',"MZ", MZ)
            
            % Update Repeated Sequence blocks in Simulink
            set_param('F3_Trajectory/R1', 'rep_seq_t', 'TIME', 'rep_seq_y','FX')
            set_param('F3_Trajectory/R2', 'rep_seq_t', 'TIME', 'rep_seq_y','FY')
            set_param('F3_Trajectory/R3', 'rep_seq_t', 'TIME', 'rep_seq_y','FZ')
            set_param('F3_Trajectory/R4', 'rep_seq_t', 'TIME', 'rep_seq_y','MX')
            set_param('F3_Trajectory/R5', 'rep_seq_t', 'TIME', 'rep_seq_y','MY')
            set_param('F3_Trajectory/R6', 'rep_seq_t', 'TIME', 'rep_seq_y','MZ')
            
            app.Lamp_Status.Color = app.GREEN;
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            app.Lamp_Status.Color = app.YELLOW;
            
            simout = sim(app.simulinkModel, 'StopTime', int2str(app.GAIT_STOP_TIME), 'FixedStep', num2str(app.STEP_SIZE));
            assignin('base', "simout", simout);
            
            % plot(app.UIAxes_Sim_Out_Force, TIME, MX, '-r');
            % sink = get_param('F3_Trajectory.slx', 'S1');
            time = evalin('base','simout.simout.time');
            a = evalin('base','simout.simout.signals.values(:,1)');
            b = evalin('base','simout.simout.signals.values(:,2)');
            c = evalin('base','simout.simout.signals.values(:,3)');
            d = evalin('base','simout.simout.signals.values(:,4)');
            e = evalin('base','simout.simout.signals.values(:,5)');
            f = evalin('base','simout.simout.signals.values(:,6)');
            plot(app.UIAxes_Sim_Out_Force, time, a, '-r');
            hold(app.UIAxes_Sim_Out_Force);
            plot(app.UIAxes_Sim_Out_Force, time, b, '-b');
            plot(app.UIAxes_Sim_Out_Force, time, c, '-g');
            hold(app.UIAxes_Sim_Out_Force);
            
            plot(app.UIAxes_Sim_Out_Torque, time, d, '-r');
            hold(app.UIAxes_Sim_Out_Torque);
            plot(app.UIAxes_Sim_Out_Torque, time, e, '-b');
            plot(app.UIAxes_Sim_Out_Torque, time, f, '-g');
            hold(app.UIAxes_Sim_Out_Torque);
            
            app.Lamp_Status.Color = app.GREEN;
        end

        % Value changed function: GaitCyclesEditField
        function GaitCyclesEditFieldValueChanged(app, event)
            value = app.GaitCyclesEditField.Value;
            app.GAIT_CYCLES = value;
            app.GAIT_STOP_TIME = app.GAIT_CYCLES * app.GAIT_TIME;
        end

        % Value changed function: StepSizeDropDown
        function StepSizeDropDownValueChanged(app, event)
            value = app.StepSizeDropDown.Value;
            app.STEP_SIZE = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LoadOpenSimDataButton = uibutton(app.GridLayout, 'push');
            app.LoadOpenSimDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadOpenSimDataButtonPushed, true);
            app.LoadOpenSimDataButton.Text = 'Load OpenSim Data';
            app.UIAxes_Torque = uiaxes(app.GridLayout);
            title(app.UIAxes_Torque, 'OpenSim Torques')
            xlabel(app.UIAxes_Torque, 'Time')
            ylabel(app.UIAxes_Torque, 'Torque (Nm)')
            app.RunSimulationButton = uibutton(app.GridLayout, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.RunTestRigForcefeedbackButton = uibutton(app.GridLayout, 'push');
            app.RunTestRigForcefeedbackButton.Text = {'Run TestRig'; '(Force feedback)'};
            app.DemoModeButton = uibutton(app.GridLayout, 'push');
            app.DemoModeButton.Text = 'Demo Mode';
            app.PatientProfilePanel = uipanel(app.GridLayout);
            app.PatientProfilePanel.Title = 'Patient Profile';
            app.GridLayout2 = uigridlayout(app.PatientProfilePanel);
            app.KgDropDownLabel = uilabel(app.GridLayout2);
            app.KgDropDownLabel.Text = 'Kg';
            app.KgDropDown = uidropdown(app.GridLayout2);
            app.KgDropDown.Items = {'50', '60', '70', '80', '90', '100'};
            app.KgDropDown.ItemsData = {'50', '60', '70', '80', '90', '100'};
            app.KgDropDown.Value = '80';
            app.UIAxes_Force = uiaxes(app.GridLayout);
            title(app.UIAxes_Force, 'OpenSim Forces')
            xlabel(app.UIAxes_Force, 'Time')
            ylabel(app.UIAxes_Force, 'Force (N)')
            app.SettingsPanel = uipanel(app.GridLayout);
            app.SettingsPanel.Title = 'Settings';
            app.GridLayout4 = uigridlayout(app.SettingsPanel);
            app.GaitCyclesEditField_2Label = uilabel(app.GridLayout4);
            app.GaitCyclesEditField_2Label.Text = 'Gait Cycles';
            app.GaitCyclesEditField = uieditfield(app.GridLayout4, 'numeric');
            app.GaitCyclesEditField.Limits = [1 20];
            app.GaitCyclesEditField.ValueChangedFcn = createCallbackFcn(app, @GaitCyclesEditFieldValueChanged, true);
            app.GaitCyclesEditField.Value = 1;
            app.StepSizeDropDownLabel = uilabel(app.GridLayout4);
            app.StepSizeDropDownLabel.Text = 'Step Size';
            app.StepSizeDropDown = uidropdown(app.GridLayout4);
            app.StepSizeDropDown.Items = {'0.1', '0.05', '0.01', '0.005', '0.001', '0.0005', '0.00001'};
            app.StepSizeDropDown.ItemsData = {'0.1', '0.05', '0.01', '0.005', '0.001', '0.0005', '0.00001'};
            app.StepSizeDropDown.ValueChangedFcn = createCallbackFcn(app, @StepSizeDropDownValueChanged, true);
            app.StepSizeDropDown.Value = '0.01';
            app.UIAxes_Sim_Out_Force = uiaxes(app.GridLayout);
            title(app.UIAxes_Sim_Out_Force, 'Simulink Forces')
            xlabel(app.UIAxes_Sim_Out_Force, 'Time')
            ylabel(app.UIAxes_Sim_Out_Force, 'Force (N)')
            app.UIAxes_Sim_Out_Torque = uiaxes(app.GridLayout);
            title(app.UIAxes_Sim_Out_Torque, 'Simulink Torques')
            xlabel(app.UIAxes_Sim_Out_Torque, 'Time')
            ylabel(app.UIAxes_Sim_Out_Torque, 'Torque (Nm)')
            app.UIAxes_Arduino_Out_Force = uiaxes(app.GridLayout);
            title(app.UIAxes_Arduino_Out_Force, 'Test Rig: Strain Gauge Forces')
            xlabel(app.UIAxes_Arduino_Out_Force, 'Time')
            ylabel(app.UIAxes_Arduino_Out_Force, 'Force (N)')
            app.UIAxes_Arduino_Out_Force_2 = uiaxes(app.GridLayout);
            title(app.UIAxes_Arduino_Out_Force_2, 'Test Rig: Position')
            xlabel(app.UIAxes_Arduino_Out_Force_2, 'Time')
            ylabel(app.UIAxes_Arduino_Out_Force_2, 'Position (mm)')
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.Image = uiimage(app.GridLayout5);
            app.Image.ImageSource = 'Socket-Sense-Logo.png';
            app.GridLayout6 = uigridlayout(app.GridLayout);
            app.Lamp_Status = uilamp(app.GridLayout6);
            app.Lamp_Arduino = uilamp(app.GridLayout6);
            app.StatusLabel = uilabel(app.GridLayout6);
            app.StatusLabel.Text = 'Status';
            app.ArduinoLabel = uilabel(app.GridLayout6);
            app.ArduinoLabel.Text = 'Arduino';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SocketSenseMainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end