classdef SocketSenseTestingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        Switch                      matlab.ui.control.Switch
        Lamp                        matlab.ui.control.Lamp
        IPEditFieldLabel            matlab.ui.control.Label
        IPEditField                 matlab.ui.control.EditField
        UsernameEditFieldLabel      matlab.ui.control.Label
        UsernameEditField           matlab.ui.control.EditField
        PasswordEditFieldLabel      matlab.ui.control.Label
        PasswordEditField           matlab.ui.control.EditField
        PWMfrequencyEditFieldLabel  matlab.ui.control.Label
        PWMfrequencyEditField       matlab.ui.control.EditField
        M1PinOutEditFieldLabel      matlab.ui.control.Label
        M1PinOutEditField           matlab.ui.control.EditField
        M2PinOutEditFieldLabel      matlab.ui.control.Label
        M2PinOutEditField           matlab.ui.control.EditField
        M3PinOutEditFieldLabel      matlab.ui.control.Label
        M3PinOutEditField           matlab.ui.control.EditField
        M4PinOutEditFieldLabel      matlab.ui.control.Label
        M4PinOutEditField           matlab.ui.control.EditField
        M5PinOutEditFieldLabel      matlab.ui.control.Label
        M5PinOutEditField           matlab.ui.control.EditField
        M6PinOutEditFieldLabel      matlab.ui.control.Label
        M6PinOutEditField           matlab.ui.control.EditField
        ModesKnobLabel              matlab.ui.control.Label
        ModesKnob                   matlab.ui.control.DiscreteKnob
        RightPanel                  matlab.ui.container.Panel
        TabGroup                    matlab.ui.container.TabGroup
        RPiTeststoutTab             matlab.ui.container.Tab
        GridLayout2                 matlab.ui.container.GridLayout
        ValSpinner_1                matlab.ui.control.Spinner
        M1PosSliderLabel            matlab.ui.control.Label
        M1PosSlider                 matlab.ui.control.Slider
        ValSpinner_2                matlab.ui.control.Spinner
        M2PosSliderLabel            matlab.ui.control.Label
        M2PosSlider                 matlab.ui.control.Slider
        ValSpinner_3                matlab.ui.control.Spinner
        M3PosSliderLabel            matlab.ui.control.Label
        M3PosSlider                 matlab.ui.control.Slider
        ValSpinner_4                matlab.ui.control.Spinner
        M4PosSliderLabel            matlab.ui.control.Label
        M4PosSlider                 matlab.ui.control.Slider
        ValSpinner_5                matlab.ui.control.Spinner
        M5PosSliderLabel            matlab.ui.control.Label
        M5PosSlider                 matlab.ui.control.Slider
        ValSpinner_6                matlab.ui.control.Spinner
        M6PosSliderLabel            matlab.ui.control.Label
        M6PosSlider                 matlab.ui.control.Slider
        RaspberryPiStatusPanel      matlab.ui.container.Panel
        Label_RPi_Status            matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        ManualServoTab              matlab.ui.container.Tab
        InverseKinTab               matlab.ui.container.Tab
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        % LeftPanel Props
        IP = "";
        USERNAME = "";
        PASSWORD = "";
        PWMFREQ = 500;
        M1_PIN = 0;
        M2_PIN = 0;
        M3_PIN = 0;
        M4_PIN = 0;
        M5_PIN = 0;
        M6_PIN = 0;
        
        % App desigenr props
        M1_SLIDER = 0;
        M2_SLIDER = 0;
        M3_SLIDER = 0;
        M4_SLIDER = 0;
        M5_SLIDER = 0;
        M6_SLIDER = 0;
        M1_SPINNER = 0;
        M2_SPINNER = 0;
        M3_SPINNER = 0;
        M4_SPINNER = 0;
        M5_SPINNER = 0;
        M6_SPINNER = 0;
        
        mypi = 0;
        
        % Lamp colors
        GREEN = [0,1,0];
        BLACK = [0,0,0];
        WHITE = [1,1,1];
        
        % Knob Mode options
        MODE_KNOB_VAL_1 = "RPi Shell Out";
        MODE_KNOB_VAL_2 = "Manual Servo";
        MODE_KNOB_VAL_3 = "Inverse Kin.";

    end
    
    methods (Access = public)
        
        function results = PiSystemScript(app)
            command = strjoin({'./motor_duty_script.sh', ...
                num2str(app.M1_SLIDER), ...
                num2str(app.M2_SLIDER), ...
                num2str(app.M3_SLIDER), ...
                num2str(app.M4_SLIDER), ...
                num2str(app.M5_SLIDER), ...
                num2str(app.M6_SLIDER), ...
                });
            % command = string2char(command);
            results = system(app.mypi, command);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;

            if(value == "On")
                
                % Initialize params
                app.IP = app.IPEditField.Value;
                app.USERNAME = app.UsernameEditField.Value;
                app.PASSWORD = app.PasswordEditField.Value;
                app.PWMFREQ = app.PWMfrequencyEditField.Value;
                app.M1_PIN = str2double(app.M1PinOutEditField.Value);
                app.M2_PIN = str2double(app.M2PinOutEditField.Value);
                app.M3_PIN = str2double(app.M3PinOutEditField.Value);
                app.M4_PIN = str2double(app.M4PinOutEditField.Value);
                app.M5_PIN = str2double(app.M5PinOutEditField.Value);
                app.M6_PIN = str2double(app.M6PinOutEditField.Value);
                
                app.mypi = raspi(app.IP, app.USERNAME, app.PASSWORD);
                
                if(value == "RPi Shell Out")
                    app.TextArea_RPi.Value = "Testing RPi with mock PWM-signals...";
                elseif (value == "Manual Servo")
                    % Pinouts
                    configurePin(app.mypi, app.M1_PIN, 'PWM');
                    configurePin(app.mypi, app.M2_PIN, 'PWM');
                    configurePin(app.mypi, app.M3_PIN, 'PWM');
                    configurePin(app.mypi, app.M4_PIN, 'PWM');
                    configurePin(app.mypi, app.M5_PIN, 'PWM');
                    configurePin(app.mypi, app.M6_PIN, 'PWM');
                    
                    % PWM Frequencies
                    writePWMFrequency(app.mypi, app.M1_PIN, app.PWMFREQ);
                    writePWMFrequency(app.mypi, app.M2_PIN, app.PWMFREQ);
                    writePWMFrequency(app.mypi, app.M3_PIN, app.PWMFREQ);
                    writePWMFrequency(app.mypi, app.M4_PIN, app.PWMFREQ);
                    writePWMFrequency(app.mypi, app.M5_PIN, app.PWMFREQ);
                    writePWMFrequency(app.mypi, app.M6_PIN, app.PWMFREQ);
                elseif (value == "Inverse Kin.")
                    % Pinouts
                    configurePin(app.mypi, app.M1_PIN, 'PWM');
                    configurePin(app.mypi, app.M2_PIN, 'PWM');
                    configurePin(app.mypi, app.M3_PIN, 'PWM');
                    configurePin(app.mypi, app.M4_PIN, 'PWM');
                    configurePin(app.mypi, app.M5_PIN, 'PWM');
                    configurePin(app.mypi, app.M6_PIN, 'PWM');
                    
                    % PWM Frequencies
                    writePWMFrequency(app.mypi, app.M1_PIN, app.PWMFREQ);
                    writePWMFrequency(app.mypi, app.M2_PIN, app.PWMFREQ);
                    writePWMFrequency(app.mypi, app.M3_PIN, app.PWMFREQ);
                    writePWMFrequency(app.mypi, app.M4_PIN, app.PWMFREQ);
                    writePWMFrequency(app.mypi, app.M5_PIN, app.PWMFREQ);
                    writePWMFrequency(app.mypi, app.M6_PIN, app.PWMFREQ);
                end
              
                app.Lamp.Color = app.GREEN;
            end
            if(value == "Off")
                %configurePin(app.mypi, 27, 'DigitalInput');
                app.mypi = 0;
                app.Lamp.Color = app.WHITE;
            end
        end

        % Value changing function: M1PosSlider
        function M1PosSliderValueChanging(app, event)
            app.M1_SLIDER = event.Value;
            app.ValSpinner_1.Value = app.M1_SLIDER;
            
            if(app.ModesKnob.Value == app.MODE_KNOB_VAL_1)
                results = PiSystemScript(app);
                app.Label_RPi_Status.Text = results;
                
            elseif (app.ModesKnob.Value == app.MODE_KNOB_VAL_2)
                writePWMDutyCycle(app.mypi, app.M1_PIN, app.M1_SLIDER);
                
            elseif (app.ModesKnob.Value == app.MODE_KNOB_VAL_3)
                
            end
            
        end

        % Value changing function: M2PosSlider
        function M2PosSliderValueChanging(app, event)
            app.M2_SLIDER = event.Value;
            app.ValSpinner_2.Value = app.M2_SLIDER;
            
            if(app.ModesKnob.Value == app.MODE_KNOB_VAL_1)
                results = PiSystemScript(app);
                app.Label_RPi_Status.Text = results;
                
            elseif (app.ModesKnob.Value == app.MODE_KNOB_VAL_2)
                writePWMDutyCycle(app.mypi, app.M2_PIN, app.M2_SLIDER);
                
            elseif (app.ModesKnob.Value == app.MODE_KNOB_VAL_3)
                
            end
        end

        % Value changing function: M3PosSlider
        function M3PosSliderValueChanging(app, event)
            app.M3_SLIDER = event.Value;
            app.ValSpinner_3.Value = app.M3_SLIDER;
            
            if(app.ModesKnob.Value == app.MODE_KNOB_VAL_1)
                results = PiSystemScript(app);
                app.Label_RPi_Status.Text = results;
                
            elseif (app.ModesKnob.Value == app.MODE_KNOB_VAL_2)
                writePWMDutyCycle(app.mypi, app.M3_PIN, app.M3_SLIDER);
                
            elseif (app.ModesKnob.Value == app.MODE_KNOB_VAL_3)
                
            end
        end

        % Value changing function: M4PosSlider
        function M4PosSliderValueChanging(app, event)
            app.M4_SLIDER = event.Value;
            app.ValSpinner_4.Value = app.M4_SLIDER;
            
            if(app.ModesKnob.Value == app.MODE_KNOB_VAL_1)
                results = PiSystemScript(app);
                app.Label_RPi_Status.Text = results;
                
            elseif (app.ModesKnob.Value == app.MODE_KNOB_VAL_2)
                writePWMDutyCycle(app.mypi, app.M4_PIN, app.M4_SLIDER);
                
            elseif (app.ModesKnob.Value == app.MODE_KNOB_VAL_3)
                
            end
            
        end

        % Value changing function: M5PosSlider
        function M5PosSliderValueChanging(app, event)
            app.M5_SLIDER = event.Value;
            app.ValSpinner_5.Value = app.M5_SLIDER;
            
            if(app.ModesKnob.Value == app.MODE_KNOB_VAL_1)
                results = PiSystemScript(app);
                app.Label_RPi_Status.Text = results;
                
            elseif (app.ModesKnob.Value == app.MODE_KNOB_VAL_2)
                writePWMDutyCycle(app.mypi, app.M5_PIN, app.M5_SLIDER);
                
            elseif (app.ModesKnob.Value == app.MODE_KNOB_VAL_3)
                
            end
            
        end

        % Value changing function: M6PosSlider
        function M6PosSliderValueChanging(app, event)
            app.M6_SLIDER = event.Value;
            app.ValSpinner_6.Value = app.M6_SLIDER;
            
            if(app.ModesKnob.Value == app.MODE_KNOB_VAL_1)
                results = PiSystemScript(app);
                app.Label_RPi_Status.Text = results;
                
            elseif (app.ModesKnob.Value == app.MODE_KNOB_VAL_2)
                writePWMDutyCycle(app.mypi, app.M6_PIN, app.M6_SLIDER);
                
            elseif (app.ModesKnob.Value == app.MODE_KNOB_VAL_3)
                
            end
            
        end

        % Value changed function: ValSpinner_1
        function ValSpinner_1ValueChanged(app, event)
            app.M1_SLIDER = app.ValSpinner_1.Value;
            app.M1PosSlider.Value = app.M1_SLIDER;
            
            if(app.ModesKnob.Value == app.MODE_KNOB_VAL_1)
                results = PiSystemScript(app);
                app.Label_RPi_Status.Text = results;
                
            elseif (app.ModesKnob.Value == app.MODE_KNOB_VAL_2)
                writePWMDutyCycle(app.mypi, app.M1_PIN, app.M1_SLIDER);
                
            elseif (app.ModesKnob.Value == app.MODE_KNOB_VAL_3)
                
            end
            
        end

        % Value changed function: ValSpinner_2
        function ValSpinner_2ValueChanged(app, event)
            app.M2_SLIDER = app.ValSpinner_2.Value;
            app.M2PosSlider.Value = app.M2_SLIDER;
            
            if(app.ModesKnob.Value == app.MODE_KNOB_VAL_1)
                results = PiSystemScript(app);
                app.Label_RPi_Status.Text = results;
                
            elseif (app.ModesKnob.Value == app.MODE_KNOB_VAL_2)
                writePWMDutyCycle(app.mypi, 27, app.M2_SLIDER);
                
            elseif (app.ModesKnob.Value == app.MODE_KNOB_VAL_3)
                
            end
            
        end

        % Value changed function: ValSpinner_3
        function ValSpinner_3ValueChanged(app, event)
            app.M3_SLIDER = app.ValSpinner_3.Value;
            app.M3PosSlider.Value = app.M3_SLIDER;
            
            if(app.ModesKnob.Value == app.MODE_KNOB_VAL_1)
                results = PiSystemScript(app);
                app.Label_RPi_Status.Text = results;
                
            elseif (app.ModesKnob.Value == app.MODE_KNOB_VAL_2)
                writePWMDutyCycle(app.mypi, 27, app.M3_SLIDER);
                
            elseif (app.ModesKnob.Value == app.MODE_KNOB_VAL_3)
                
            end
            
        end

        % Value changed function: ValSpinner_4
        function ValSpinner_4ValueChanged(app, event)
            app.M4_SLIDER = app.ValSpinner_4.Value;
            app.M4PosSlider.Value = app.M4_SLIDER;
            
            if(app.ModesKnob.Value == app.MODE_KNOB_VAL_1)
                results = PiSystemScript(app);
                app.Label_RPi_Status.Text = results;
                
            elseif (app.ModesKnob.Value == app.MODE_KNOB_VAL_2)
                writePWMDutyCycle(app.mypi, 27, app.M4_SLIDER);
                
            elseif (app.ModesKnob.Value == app.MODE_KNOB_VAL_3)
                
            end
            
        end

        % Value changed function: ValSpinner_5
        function ValSpinner_5ValueChanged(app, event)
            app.M5_SLIDER = app.ValSpinner_5.Value;
            app.M5PosSlider.Value = app.M5_SLIDER;
            
            if(app.ModesKnob.Value == app.MODE_KNOB_VAL_1)
                results = PiSystemScript(app);
                app.Label_RPi_Status.Text = results;
                
            elseif (app.ModesKnob.Value == app.MODE_KNOB_VAL_2)
                writePWMDutyCycle(app.mypi, 27, app.M5_SLIDER);
                
            elseif (app.ModesKnob.Value == app.MODE_KNOB_VAL_3)
                
            end
            
        end

        % Value changed function: ValSpinner_6
        function ValSpinner_6ValueChanged(app, event)
            app.M6_SLIDER = app.ValSpinner_6.Value;
            app.M6PosSlider.Value = app.M6_SLIDER;
            
            if(app.ModesKnob.Value == app.MODE_KNOB_VAL_1)
                results = PiSystemScript(app);
                app.Label_RPi_Status.Text = results;
                
            elseif (app.ModesKnob.Value == app.MODE_KNOB_VAL_2)
                writePWMDutyCycle(app.mypi, 27, app.M6_SLIDER);
                
            elseif (app.ModesKnob.Value == app.MODE_KNOB_VAL_3)
                
            end
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {692, 692};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Switch = uiswitch(app.LeftPanel, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Lamp = uilamp(app.LeftPanel);
            app.IPEditFieldLabel = uilabel(app.LeftPanel);
            app.IPEditFieldLabel.Text = 'IP:';
            app.IPEditField = uieditfield(app.LeftPanel, 'text');
            app.IPEditField.Value = '192.168.1.12';
            app.UsernameEditFieldLabel = uilabel(app.LeftPanel);
            app.UsernameEditFieldLabel.Text = 'Username';
            app.UsernameEditField = uieditfield(app.LeftPanel, 'text');
            app.UsernameEditField.Value = 'pi';
            app.PasswordEditFieldLabel = uilabel(app.LeftPanel);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.LeftPanel, 'text');
            app.PasswordEditField.Value = 'socket';
            app.PWMfrequencyEditFieldLabel = uilabel(app.LeftPanel);
            app.PWMfrequencyEditFieldLabel.Text = 'PWM frequency';
            app.PWMfrequencyEditField = uieditfield(app.LeftPanel, 'text');
            app.PWMfrequencyEditField.Value = '50';
            app.M1PinOutEditFieldLabel = uilabel(app.LeftPanel);
            app.M1PinOutEditFieldLabel.Text = 'M1 PinOut';
            app.M1PinOutEditField = uieditfield(app.LeftPanel, 'text');
            app.M1PinOutEditField.Value = '18';
            app.M2PinOutEditFieldLabel = uilabel(app.LeftPanel);
            app.M2PinOutEditFieldLabel.Text = 'M2 PinOut';
            app.M2PinOutEditField = uieditfield(app.LeftPanel, 'text');
            app.M2PinOutEditField.Value = '23';
            app.M3PinOutEditFieldLabel = uilabel(app.LeftPanel);
            app.M3PinOutEditFieldLabel.Text = 'M3 PinOut';
            app.M3PinOutEditField = uieditfield(app.LeftPanel, 'text');
            app.M3PinOutEditField.Value = '24';
            app.M4PinOutEditFieldLabel = uilabel(app.LeftPanel);
            app.M4PinOutEditFieldLabel.Text = 'M4 PinOut';
            app.M4PinOutEditField = uieditfield(app.LeftPanel, 'text');
            app.M4PinOutEditField.Value = '22';
            app.M5PinOutEditFieldLabel = uilabel(app.LeftPanel);
            app.M5PinOutEditFieldLabel.Text = 'M5 PinOut';
            app.M5PinOutEditField = uieditfield(app.LeftPanel, 'text');
            app.M5PinOutEditField.Value = '27';
            app.M6PinOutEditFieldLabel = uilabel(app.LeftPanel);
            app.M6PinOutEditFieldLabel.Text = 'M6 PinOut';
            app.M6PinOutEditField = uieditfield(app.LeftPanel, 'text');
            app.M6PinOutEditField.Value = '17';
            app.ModesKnobLabel = uilabel(app.LeftPanel);
            app.ModesKnobLabel.Text = 'Modes';
            app.ModesKnob = uiknob(app.LeftPanel, 'discrete');
            app.ModesKnob.Items = {'Off', 'RPi Shell Out', 'Manual Servo', 'Inverse Kin.'};
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.RPiTeststoutTab = uitab(app.TabGroup);
            app.RPiTeststoutTab.Title = 'RPi Test stout';
            app.GridLayout2 = uigridlayout(app.RPiTeststoutTab);
            app.ValSpinner_1 = uispinner(app.GridLayout2);
            app.ValSpinner_1.Step = 0.05;
            app.ValSpinner_1.Limits = [0 1];
            app.ValSpinner_1.ValueChangedFcn = createCallbackFcn(app, @ValSpinner_1ValueChanged, true);
            app.M1PosSliderLabel = uilabel(app.GridLayout2);
            app.M1PosSliderLabel.Text = 'M1 Pos';
            app.M1PosSlider = uislider(app.GridLayout2);
            app.M1PosSlider.Limits = [0 1];
            app.M1PosSlider.MajorTicks = [0 0.5 1];
            app.M1PosSlider.MajorTickLabels = {'0', '0.5', '1'};
            app.M1PosSlider.ValueChangingFcn = createCallbackFcn(app, @M1PosSliderValueChanging, true);
            app.M1PosSlider.MinorTicks = [0.1 0.2 0.3 0.4 0.6 0.7 0.8 0.9];
            app.ValSpinner_2 = uispinner(app.GridLayout2);
            app.ValSpinner_2.Step = 0.05;
            app.ValSpinner_2.Limits = [0 1];
            app.ValSpinner_2.ValueChangedFcn = createCallbackFcn(app, @ValSpinner_2ValueChanged, true);
            app.M2PosSliderLabel = uilabel(app.GridLayout2);
            app.M2PosSliderLabel.Text = 'M2 Pos';
            app.M2PosSlider = uislider(app.GridLayout2);
            app.M2PosSlider.Limits = [0 1];
            app.M2PosSlider.MajorTicks = [0 0.5 1];
            app.M2PosSlider.MajorTickLabels = {'0', '0.5', '1'};
            app.M2PosSlider.ValueChangingFcn = createCallbackFcn(app, @M2PosSliderValueChanging, true);
            app.M2PosSlider.MinorTicks = [0.1 0.2 0.3 0.4 0.6 0.7 0.8 0.9];
            app.ValSpinner_3 = uispinner(app.GridLayout2);
            app.ValSpinner_3.Step = 0.05;
            app.ValSpinner_3.Limits = [0 1];
            app.ValSpinner_3.ValueChangedFcn = createCallbackFcn(app, @ValSpinner_3ValueChanged, true);
            app.M3PosSliderLabel = uilabel(app.GridLayout2);
            app.M3PosSliderLabel.Text = 'M3 Pos';
            app.M3PosSlider = uislider(app.GridLayout2);
            app.M3PosSlider.Limits = [0 1];
            app.M3PosSlider.MajorTicks = [0 0.5 1];
            app.M3PosSlider.MajorTickLabels = {'0', '0.5', '1'};
            app.M3PosSlider.ValueChangingFcn = createCallbackFcn(app, @M3PosSliderValueChanging, true);
            app.M3PosSlider.MinorTicks = [0.1 0.2 0.3 0.4 0.6 0.7 0.8 0.9];
            app.ValSpinner_4 = uispinner(app.GridLayout2);
            app.ValSpinner_4.Step = 0.05;
            app.ValSpinner_4.Limits = [0 1];
            app.ValSpinner_4.ValueChangedFcn = createCallbackFcn(app, @ValSpinner_4ValueChanged, true);
            app.M4PosSliderLabel = uilabel(app.GridLayout2);
            app.M4PosSliderLabel.Text = 'M4 Pos';
            app.M4PosSlider = uislider(app.GridLayout2);
            app.M4PosSlider.Limits = [0 1];
            app.M4PosSlider.MajorTicks = [0 0.5 1];
            app.M4PosSlider.MajorTickLabels = {'0', '0.5', '1'};
            app.M4PosSlider.ValueChangingFcn = createCallbackFcn(app, @M4PosSliderValueChanging, true);
            app.M4PosSlider.MinorTicks = [0.1 0.2 0.3 0.4 0.6 0.7 0.8 0.9];
            app.ValSpinner_5 = uispinner(app.GridLayout2);
            app.ValSpinner_5.Step = 0.05;
            app.ValSpinner_5.Limits = [0 1];
            app.ValSpinner_5.ValueChangedFcn = createCallbackFcn(app, @ValSpinner_5ValueChanged, true);
            app.M5PosSliderLabel = uilabel(app.GridLayout2);
            app.M5PosSliderLabel.Text = 'M5 Pos';
            app.M5PosSlider = uislider(app.GridLayout2);
            app.M5PosSlider.Limits = [0 1];
            app.M5PosSlider.MajorTicks = [0 0.5 1];
            app.M5PosSlider.MajorTickLabels = {'0', '0.5', '1'};
            app.M5PosSlider.ValueChangingFcn = createCallbackFcn(app, @M5PosSliderValueChanging, true);
            app.M5PosSlider.MinorTicks = [0.1 0.2 0.3 0.4 0.6 0.7 0.8 0.9];
            app.ValSpinner_6 = uispinner(app.GridLayout2);
            app.ValSpinner_6.Step = 0.05;
            app.ValSpinner_6.Limits = [0 1];
            app.ValSpinner_6.ValueChangedFcn = createCallbackFcn(app, @ValSpinner_6ValueChanged, true);
            app.M6PosSliderLabel = uilabel(app.GridLayout2);
            app.M6PosSliderLabel.Text = 'M6 Pos';
            app.M6PosSlider = uislider(app.GridLayout2);
            app.M6PosSlider.Limits = [0 1];
            app.M6PosSlider.MajorTicks = [0 0.5 1];
            app.M6PosSlider.MajorTickLabels = {'0', '0.5', '1'};
            app.M6PosSlider.ValueChangingFcn = createCallbackFcn(app, @M6PosSliderValueChanging, true);
            app.M6PosSlider.MinorTicks = [0.1 0.2 0.3 0.4 0.6 0.7 0.8 0.9];
            app.RaspberryPiStatusPanel = uipanel(app.GridLayout2);
            app.RaspberryPiStatusPanel.Title = 'Raspberry Pi Status';
            app.Label_RPi_Status = uilabel(app.RaspberryPiStatusPanel);
            app.Label_RPi_Status.Text = '';
            app.Image = uiimage(app.GridLayout2);
            app.Image.ImageSource = 'SocketSense_logo2.png';
            app.ManualServoTab = uitab(app.TabGroup);
            app.ManualServoTab.Title = 'Manual Servo';
            app.InverseKinTab = uitab(app.TabGroup);
            app.InverseKinTab.Title = 'Inverse Kin.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SocketSenseTestingApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end